classdef DataHub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        WaveformPanel             matlab.ui.container.Panel
        ClusterButton             matlab.ui.control.Button
        EditButton_2              matlab.ui.control.Button
        NewWindowButton           matlab.ui.control.Button
        GoButton_2                matlab.ui.control.Button
        Lamp_2                    matlab.ui.control.Lamp
        UIAxes                    matlab.ui.control.UIAxes
        TaskAnalysisPanel         matlab.ui.container.Panel
        CLFButton                 matlab.ui.control.Button
        TaskNameDropDown          matlab.ui.control.DropDown
        ButtonGroup_3             matlab.ui.container.ButtonGroup
        OutputCheckBox            matlab.ui.control.CheckBox
        ShowfigCheckBox           matlab.ui.control.CheckBox
        GoButton                  matlab.ui.control.Button
        EditProtocolButton        matlab.ui.control.Button
        ProtocolButtonPanel       matlab.ui.container.Panel
        AddProtocolButton         matlab.ui.control.Button
        SpikeChannelDropDown      matlab.ui.control.DropDown
        SpikeChannelLabel         matlab.ui.control.Label
        BehaviorSummary           matlab.ui.control.TextArea
        Lamp                      matlab.ui.control.Lamp
        EndTrialEditField         matlab.ui.control.NumericEditField
        EndTrialEditFieldLabel    matlab.ui.control.Label
        StartTrialEditField       matlab.ui.control.NumericEditField
        StartTrialEditFieldLabel  matlab.ui.control.Label
        ButtonGroup_2             matlab.ui.container.ButtonGroup
        UpdateDataButton          matlab.ui.control.Button
        AllDataButton             matlab.ui.control.RadioButton
        GoodDataButton_2          matlab.ui.control.RadioButton
        TaskNameLabel             matlab.ui.control.Label
        AnalysesDropDown          matlab.ui.control.DropDown
        AnalysesDropDownLabel     matlab.ui.control.Label
        ButtonGroup               matlab.ui.container.ButtonGroup
        NeuronFileDropDown        matlab.ui.control.DropDown
        BehaviorFileDropDown      matlab.ui.control.DropDown
        WaveformButton            matlab.ui.control.StateButton
        BehaviorFileButton        matlab.ui.control.StateButton
        DataLoadPanel             matlab.ui.container.Panel
        RePackCheckBox            matlab.ui.control.CheckBox
        EditLoadPackButton        matlab.ui.control.Button
        DateEditField             matlab.ui.control.EditField
        DateEditFieldLabel        matlab.ui.control.Label
        ECodeButton               matlab.ui.control.Button
        BatchCheckBox             matlab.ui.control.CheckBox
        BrowseButton              matlab.ui.control.Button
        LoadButton                matlab.ui.control.Button
        OSButtonGroup             matlab.ui.container.ButtonGroup
        LabCheckBox               matlab.ui.control.CheckBox
        MacButton                 matlab.ui.control.RadioButton
        PCButton                  matlab.ui.control.RadioButton
        BasePathEditField         matlab.ui.control.EditField
        BasePathLabel             matlab.ui.control.Label
    end

    
    properties (Access = public)
        GoodData;%The valid database;
        AllData;%All database;
        UseData;%The dataset transferred for further analyzing
        SpikeData;%The Spike dataset storage
       WaveformData;%To store the spike waveform
        SystemLabel;%For convenience of data loading;Windows :'\'; 2:Mac:'/'
        RawDataPath;%To store the datapath 
        PackedDataPath;%To store the packed datapath;
        RecoverPathFromBatch;%To store the previous data path before batch
        RecoverAnalysisFromBatch;%To store the previous analysis protocol before batch
        BATCH=-999;%Batch label
        BatchData;%To store batch file information
        UpdateIndex=0;%Index to indicate whether updates happend
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        clc
        close all
        %Initiation all the variables shown on the pannel
             app.UIFigure.Name = 'Xuefei''s Data Hub | Version 2.0';
             app.UIFigure.Resize='on';
          
             
             app.BehaviorFileDropDown.Items={'Select/Input a file','101719FRAC1.rst'};
             app.BehaviorFileDropDown.Value={app.BehaviorFileDropDown.Items{1}};
             
             
             app.NeuronFileDropDown.Items={'Select/Input a file'};
             app.NeuronFileDropDown.Value={app.NeuronFileDropDown.Items{1}};
             
            
             app.BehaviorFileButton.Value=true;
             app.WaveformButton.Value=false;
             
             app.ShowfigCheckBox.Value=true;
             app.OutputCheckBox.Value=true;
             
              %Add path
             % Determine where the app is.
               folderpath = fileparts(which('DataHub')); 
             % Add that folder plus all subfolders to the path.
               addpath(genpath(folderpath));


             OperationSystem = computer;%Get the operation system information
             
             if strcmp(OperationSystem(1:3),"PCW")   
             app.PCButton.Value=true;
             app.MacButton.Value=false;
             app.MacButton.Enable=false;
             %app.BasePathEditField.Value = 'H:\ProgramesInOHLab\DataAnalysis\DataFromSystem\'; 
            
          %   app.BasePathEditField.Value = 'C:\Users\yux8\AppData\Roaming\MathWorks\MATLAB Add-Ons\Apps\DataHub\DataRecorded\'; 
            app.BasePathEditField.Value = strcat( folderpath,'\DataRecorded\'); 
         
          %Get the current date
             formatOut = 'mmddyy';
             app.DateEditField.Value=datestr(now,formatOut);
             app.SystemLabel='\';
             app.TaskNameDropDown.FontSize=15;
             
           
             elseif strcmp(OperationSystem(1:3),"MAC")           
             app.PCButton.Value=false;
             app.MacButton.Value=true; 
             app.PCButton.Enable=false;
             
             flag_loc=strfind(folderpath,'DataHub_YXF');
             
             app.BasePathEditField.Value = strcat( folderpath(1:flag_loc-1),'DataFromSystem/'); 
             %app.BasePathEditField.Value = '/Users/yux8/WorkRelated_YXF/Program_Matlab_Local/DataAnalysis/DataFromSystem/';
             %Get the current date
             formatOut = 'mmddyy';
             app.DateEditField.Value=datestr(now,formatOut);
             app.SystemLabel='/';
           
             
             app.SpikeChannelLabel.FontSize=15;
            
              
             app.AnalysesDropDownLabel.FontSize=15;
             app.StartTrialEditFieldLabel.FontSize=15;
             app.EndTrialEditFieldLabel.FontSize=15;
             app.TaskNameLabel.FontSize=13;
             app.AnalysesDropDownLabel.FontSize=13;
             
             app.TaskNameDropDown.FontSize=13;
             
             
             else
               app.PCButton.Enable=false;
               app.MacButton.Enable=false;
               uiwait(msgbox('Invalid System', 'Error','error'));
             end  
             
             %Store the current base path in case of batch analysis
             app.RecoverPathFromBatch=app.BasePathEditField.Value;
                    
             app.TaskNameDropDown.Enable=false;
             app.TaskNameDropDown.Editable=false;
             app.TaskNameDropDown.Items={'Please load the data first!'};
             app.TaskNameDropDown.ItemsData=1;
             app.TaskNameDropDown.FontColor=[0.5,0.5,0.5];
             app.TaskNameDropDown.FontName = 'Adobe Arabic';
         
             app.AnalysesDropDown.Items={'Please select a program!'};
             app.AnalysesDropDown.FontColor=[0.5,0.5,0.5];
             app.AnalysesDropDown.Enable=false;
             app.SpikeChannelDropDown.Items={'No spike channel yet!'};
             app.SpikeChannelDropDown.ItemsData=1;
             app.SpikeChannelDropDown.FontColor=[0.5,0.5,0.5];
             app.SpikeChannelDropDown.Enable=false;
             app.UpdateDataButton.Enable=false;
             
             app.StartTrialEditField.FontColor=[0.5,0.5,0.5];
             app.EndTrialEditField.FontColor=[0.5,0.5,0.5];
             app.StartTrialEditField.Editable=false;
             app.EndTrialEditField.Editable=false;
             app.EditProtocolButton.Enable=false;
             app.NewWindowButton.Enable=false;
             app.ClusterButton.Enable=false;
             app.GoButton.Enable=false;
             app.GoButton_2.Enable=false;
             app.UIAxes.Color=[0.9,0.9,0.9];


        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
        %Clear the path
        app.NeuronFileDropDown.Items={'Select/Input a file'};
        
  
        %Frist check whether neuron/behavior file button has been pressed
        if (app.BehaviorFileButton.Value||app.WaveformButton.Value)
            
            if app.BatchCheckBox.Value==1
                %Batch analysis--Load batch files
                if app.BehaviorFileButton.Value %Batch Analysis for behavior files
                     [FileName,FilePath ]= uigetfile(fullfile(app.BasePathEditField.Value,'*.txt'),'Select one file');%Get batch file
                     
                    %Get batch file name
                    if FilePath %If there is a file loaded
                        app.BehaviorFileDropDown.Value=FileName;
                        BehaviorFileDropDownValueChanged(app, event); 
                        
                    end
                end


            else

            %Single cell analysis
            
           app.RawDataPath=strcat(app.BasePathEditField.Value,app.DateEditField.Value, app.SystemLabel);
          if ~isempty(app.RawDataPath)
              
              if ~exist(app.RawDataPath)
                  
                   [FileName,FilePath ]= uigetfile([app.BasePathEditField.Value '*.*']);        
              else
                 

                   [FileName,FilePath ]= uigetfile(fullfile(app.RawDataPath,'*.rst;*.wv'),'Select one file');
             end
             if FilePath %If there is a file loaded
                app.RawDataPath=FilePath;
                splitpath=split(FilePath,app.SystemLabel);
                app.DateEditField.Value=splitpath{end-1};
                %Select Edit Field according to the file type
                FileTypeIndex=find(ismember(FileName,'.'));
                FileType=FileName(FileTypeIndex+1:end);
                if strcmp(FileType,'wv')
                   app.WaveformButton.Value=1;
                    app.NeuronFileDropDown.Value=FileName; 
                    NeuronFileDropDownValueChanged(app, event);
                   
                end %End of the vm file
                if strcmp(FileType,'rst')
                   app.BehaviorFileButton.Value=1;
                    app.BehaviorFileDropDown.Value=FileName;
                    BehaviorFileDropDownValueChanged(app, event); 
                    
                       %Automatically search for the waveform file
                      WaveformFileAuto=[app.DateEditField.Value 'SPK' FileName(end-4) '.wv'];
                        if isfile([app.RawDataPath,WaveformFileAuto])
                        app.NeuronFileDropDown.Value=WaveformFileAuto;
                        app.WaveformButton.Value=1;
                        NeuronFileDropDownValueChanged(app, event); 
                        else
                            app.NeuronFileDropDown.Value={app.NeuronFileDropDown.Items{1}};
                             app.WaveformButton.Value=0;
                            NeuronFileDropDownValueChanged(app, event);                       
                        end 
                       
                
                end %End of the rst file


             end
             
          else
            uiwait(msgbox('No data path defined!'));      
          end %End of the if not empty raw file
            end %End of the if batch checked
         else
            uiwait(msgbox('Neither the Behavior File nor the Neuron File was selected!'));       
        end 
        
           
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
         try
         disp('Loading....')
         %Clear all data
         app.GoodData=[];
         app.AllData=[];
         app.UseData=[];
         app.SpikeData=[];
         app.WaveformData=[];


         app.SpikeChannelDropDown.Items={'No spike channel yet!'};
       
         app.TaskNameDropDown.Items={'Processing...Please wait for a moment!'};
         app.AnalysesDropDown.Items={'Please select a program!'};
         cla(app.UIAxes);
         
         app.BehaviorSummary.Value={'Processing...Please wait for a moment!'};
         %Disable the data analysis panel
         app.GoButton.Enable=false;
         app.EditProtocolButton.Enable=false;
         app.UpdateDataButton.Enable=false;
         app.StartTrialEditField.Value=-1;
         app.EndTrialEditField.Value=-1;
         
         %Define the packed data folder 
         PackedDataFolder='PackedData';
         dataPathPacked=[app.RecoverPathFromBatch PackedDataFolder app.SystemLabel];
         app.PackedDataPath=dataPathPacked;
         
         %Define the Plexon Data folder
        % PlexonDataFolder='PlexonData';
        % PlexonParsedDataFolder='ParsedData';
          
         
            if app.BehaviorFileButton.Value
                if ~app.BatchCheckBox.Value          
                      if strcmp(app.BehaviorFileDropDown.Value,app.BehaviorFileDropDown.Items{1})    
                         uiwait(msgbox('Select or input a file name first!'));   
                         LoadDataSuccessFlag=0;
                      else
                           BehaviorFileDropDownValueChanged(app, event)
                          %First try to load preciously packed data to save
                          %time
                          
                          
                          fileNamePack= app.BehaviorFileDropDown.Value;
                          fileNamePack=[fileNamePack(1:end-3) 'mat'];
                          
                         if ~exist(dataPathPacked)
                             mkdir(dataPathPacked);                    
                         end
                          cd(dataPathPacked);
                          if ~exist(fileNamePack) || app.RePackCheckBox.Value
                              disp('Re-packing data... Please wait!')
                              %Repack data
                               dataPathFull=[app.RawDataPath app.BehaviorFileDropDown.Value];
                               [app.GoodData,app.AllData]=LoadAndExportData(dataPathFull,dataPathPacked,fileNamePack); 
                               LoadDataSuccessFlag=1;
                          else
                              load(fileNamePack);
                              app.GoodData=GoodData;
                              app.AllData=AllData;
                              LoadDataSuccessFlag=1;
                          
                          end
                  
        
                      end 
                      
                      
                else %For batch analysis 
                    %Added by Xuefei Yu 06032020
                     if strcmp(app.BehaviorFileDropDown.Value,app.BehaviorFileDropDown.Items{1})    
                         uiwait(msgbox('Select or input a file name first!'));   
                         LoadDataSuccessFlag=0;
                     else
                          BehaviorFileDropDownValueChanged(app, event)
                    %First determine whether need to repack the data
                   % BatchFilePath=[app.BasePathEditField.Value,app.BehaviorFileDropDown.Value];
                   
                    BatchFileAll=CheckBatchFile(app.BasePathEditField.Value,app.BehaviorFileDropDown.Value,app.RecoverPathFromBatch,app.PackedDataPath,app.RePackCheckBox.Value,app.WaveformButton.Value);
                    app.BatchData=BatchFileAll;
                    %Show batch file summary
                     FileNameShown=sprintf(['File Name:' app.BehaviorFileDropDown.Value '\n']);
                  
                     BatchFileSummary=sprintf('File Number: %d\n',BatchFileAll.FileNumber);               
                     app.BehaviorSummary.Value=[ FileNameShown BatchFileSummary];


                     LoadDataSuccessFlag=1;
                     end
                end 
                if LoadDataSuccessFlag
                   %Enable the data analysis panel
                   disp('Successfully loaded!')
                   app.GoButton.Enable=true;
                   app.EditProtocolButton.Enable=true;
                   
                
                   app.StartTrialEditField.Editable=true;
                   app.EndTrialEditField.Editable=true;
                   app.StartTrialEditField.FontColor='k';
                   app.EndTrialEditField.FontColor='k';
                   app.AnalysesDropDown.Enable=true;
                   app.AnalysesDropDown.FontColor='k';
                   app.UpdateDataButton.Enable=true;
                   
                   if app.BatchCheckBox.Value
                    %Batch analysis   
                    %Enable task type dropdown
                    app.TaskNameDropDown.Enable=true;
                    
                    app.TaskNameDropDown.Items={BatchFileAll.TaskType,'Batch Protocol'};
                    app.TaskNameDropDown.ItemsData=[1,2];
                    app.TaskNameDropDown.FontColor='k';
                    app.TaskNameDropDown.FontWeight='bold';


                   %Show the analysis protocol
                   app.AnalysesDropDown.Items= DataAnalysisListSwitchyard(BatchFileAll.TaskCode);%Show all the corresponding protocols;
                   app.RecoverAnalysisFromBatch=app.AnalysesDropDown.Items;%To store it for batch analysis
                   %Show spike channel
                   app.SpikeChannelDropDown.FontColor='k';
                   app.SpikeChannelDropDown.FontWeight='Bold';
                   app.SpikeChannelDropDown.Items={'Batch File Defined'};
                   app.SpikeChannelDropDown.ItemsData=1;
                   app.SpikeChannelDropDown.Value=1;
                   
                   
                   %Show start file and end file on the panel
                   app.StartTrialEditField.Value=1;
                   app.EndTrialEditField.Value=BatchFileAll.FileNumber;
                   
                   
                   else               
                   %Single neuron analysis
                   %Show task type
                   
                   app.TaskNameDropDown.Items={app.AllData.TaskType};
                   app.TaskNameDropDown.FontColor='k';
                   app.TaskNameDropDown.FontWeight='bold';
                   
                   %{ 
                   
                   if app.GoodDataButton_2.Value
                       %Only use gooddata;                 
                           
                           app.UseData=app.GoodData;
                           app.UseData.DataType={'Good Data'};
                           app.SpikeData.SpikeTimeData=app.GoodData.SpikeTimeData;
                           app.SpikeData.SpikeBinData=app.GoodData.SpikeBinData;
                           app.SpikeData.ChannelID=app.GoodData.ChannelID;
                           app.SpikeData.ClusterID=app.GoodData.ClusterID;
                         %  app.SpikeData.SpikeOrder=app.GoodData.SpikeReOrderIndex;
                           
                           
                           if isfield(app.GoodData,'LFPValidIndex')
                            app.SpikeData.LFPValidIndex=app.GoodData.LFPValidIndex;
                           else
                               app.SpikeData.LFPValidIndex=0;
                           end
                       
                   else
                          app.UseData=app.AllData;
                          app.UseData.DataType={'All Data'};
                          app.SpikeData.SpikeTimeData=app.AllData.SpikeTimeData;
                          app.SpikeData.SpikeBinData=app.AllData.SpikeBinData;
                          app.SpikeData.ChannelID=app.AllData.ChannelID;
                          app.SpikeData.ClusterID=app.AllData.ClusterID;
                         % app.SpikeData.SpikeOrder=app.AllData.SpikeReOrderIndex;
                           
                          if isfield(app.AllData,'LFPValidIndex')
                            app.SpikeData.LFPValidIndex=app.AllData.LFPValidIndex;
                           else
                               app.SpikeData.LFPValidIndex=0;
                          end
                      
                   end
                   %}
                   UpdateDataButtonPushed(app,event);
                %   app.StartTrialEditField.Value=1;    
                %   app.EndTrialEditField.Value=app.UseData.TrialNum;   
                  
                 %{  
                   if sum(isnan(app.SpikeData.SpikeTimeData),'All')~=numel(app.SpikeData.SpikeTimeData)
                       
                       SpikeChannelNumber=size(app.SpikeData.SpikeTimeData,1); 
                  
                       if SpikeChannelNumber>1
                            app.SpikeChannelDropDown.Enable=true;
                       else
                           app.SpikeChannelDropDown.Enable=false; 
                       end
                       app.SpikeChannelDropDown.FontColor='k';
                       app.SpikeChannelDropDown.FontWeight='Bold';
                       
                       for i=1:SpikeChannelNumber
                           TotalSpikeNumber=sum(~isnan(app.UseData.SpikeTimeData(i,:,:)),'all')-size(squeeze(app.UseData.SpikeTimeData(i,:,:)),2)*2;
                           app.SpikeChannelDropDown.Items{i}=strcat(int2str(i),'Channel: ',int2str(app.SpikeData.ChannelID(i)),' ID: ', int2str(app.SpikeData.ClusterID(i)),'(',num2str(TotalSpikeNumber),')');
                           app.SpikeChannelDropDown.ItemsData(i)=i+1;
                           app.SpikeData.TotalSpikeNum(i)=TotalSpikeNumber;
                       
                       end
                       
                       app.SpikeChannelDropDown.Items{i+1}='All online channels';
                       app.SpikeChannelDropDown.ItemsData(i+1)=i+2;
                       if SpikeChannelNumber>3
                           app.SpikeChannelDropDown.Items{i+2}='All plexon channels';
                           app.SpikeChannelDropDown.ItemsData(i+2)=i+3;
                       end
                       if app.SpikeData.LFPValidIndex
                           app.SpikeChannelDropDown.Items{i+3}='All LFP channels';
                           app.SpikeChannelDropDown.ItemsData(i+3)=i+4;
                           
                       end
                       
                        UpdateDataButtonPushed(app,event);
                       
                   else
                       %No spike channel
                       app.SpikeChannelDropDown.Enable=false; 
                       
                   
                   end
                %}
                  
                %  app.UpdateIndex=0;%Initialize update index


                   %Show behavior performance summary in the textbox
                   TrialInform=app.UseData.BehaviorSummary;
                   FixationRate=TrialInform('CorrectTrialNum')/TrialInform('TotalTrialNum')*100;
                   
                   FileNameShown=sprintf(['File Name:' app.BehaviorFileDropDown.Value '\n']);
                   FixationRate_Shown=sprintf('Fixation Rate(%%): %0.2f\n',FixationRate);
                   TotalTrialsShown=sprintf('Total Trials: %d\n',TrialInform('TotalTrialNum'));
                   GoodTrialsShown=sprintf('Successful Trials: %d\n',TrialInform('CorrectTrialNum'));
                   BreakTrialsShown=sprintf('Break Fixation Trials: %d\n',TrialInform('BreakTrialNum'));
                   NoFixTrialsShown=sprintf('No Fixation Trials: %d\n',TrialInform('NoFixTrialNum'));
                   FailSacTrialsShown=sprintf('Failed Saccade Trials: %d\n',TrialInform('FailSacTrialNum'));
                   OtherFailedTrialsShown=sprintf('Other Failed Trials: %d\n',TrialInform('OthersNum'));
                   BonusTrialsShown=sprintf('Bonus Trials: %d\n',TrialInform('BonusTrialNum'));
                   
                
                   app.BehaviorSummary.Value=[ FileNameShown FixationRate_Shown TotalTrialsShown GoodTrialsShown BreakTrialsShown NoFixTrialsShown FailSacTrialsShown  OtherFailedTrialsShown BonusTrialsShown];

                   %Show the analysis protocol
                   app.AnalysesDropDown.Items= DataAnalysisListSwitchyard(app.UseData.TaskCode);%Show all the corresponding protocols;
                   end %End of the if batch analysis
                    %Window shaking
                
                    for mm=1:50 %Shake 50 times
                        current_position=app.UIFigure.Position;
                        current_position(1)=current_position(1)+20*(-1)^mm;
                        app.UIFigure.Position=current_position;
                     end
                end


           end
           if app.WaveformButton.Value 
               if ~app.BatchCheckBox.Value 
                     if strcmp(app.NeuronFileDropDown.Value,app.NeuronFileDropDown.Items{1})
                        uiwait(msgbox('Select or input a file name first!')); 
                        LoadDataSuccessFlag=0;
                     else
                         dataPathFull=[app.RawDataPath app.NeuronFileDropDown.Value];
                         WaveformData=LoadWaveform(dataPathFull);
                          SpikeType=length(unique(WaveformData.Type))-1;
                 for i=1:SpikeType
                   % T0=[0: unique( app.WaveformData.SR( app.WaveformData.Type==i-1)):(unique( app.WaveformData.DataPointNum( app.WaveformData.Type==i-1))-1)*unique( app.WaveformData.SR( app.WaveformData.Type==i-1))];
                %  T0=0:1/unique( WaveformData.SR(WaveformData.Type==i-1)):1/unique( app.WaveformData.SR( app.WaveformData.Type==i-1))*size(app.WaveformData.Wform(app.WaveformData.Type==i-1,:),2);
                 %    T0=T0(2:end)*10^3;
                     Wform(i,:)=median(WaveformData.Wform(WaveformData.Type==i-1,:),1,'omitnan');
                    
                 end
                         app.WaveformData.Wform=Wform;
                         app.WaveformData.Path=dataPathFull;
                       %  app.WaveformData.Order=[];
                         clear WaveformData;
                         LoadDataSuccessFlag=1;
                        
                     end 
               else
                  %Batch analysis for waveform
 
                        if ~isempty(app.BatchData.WaveFormFile)
                        
                            LoadDataSuccessFlag=1;
                        else
                            LoadDataSuccessFlag=0;
                        end
                   
               end
               if  LoadDataSuccessFlag
                   app.GoButton_2.Enable=true;
                   app.ClusterButton.Enable=true;
                   app.NewWindowButton.Enable=true;
              
               
               end
           else
             if ~app.BatchCheckBox.Value 
               %Can also be load from Plexon
               if isfield(app.AllData,'SpikeWaveForm_Med') & (~isempty(app.AllData.SpikeWaveForm_Med))
                   app.GoButton_2.Enable=true;
                   app.ClusterButton.Enable=true;
                   app.NewWindowButton.Enable=true;
                   app.NeuronFileDropDown.Items{1}=app.AllData.PlexonFileName;
                   app.NeuronFileDropDown.Enable=false;
                   app.WaveformData.Wform=app.AllData.SpikeWaveForm_Med;
                   app.WaveformData.Path=strcat(app.AllData.PlexonFilePath,app.AllData.PlexonFileName(1:end-4),'wv.mat');
                 %  app.WaveformData.Order=app.SpikeData.SpikeOrder;
                   %    app.WaveformData.SR=app.AllData.SpikeWaveFormSample.SR;
               %     app.WaveformData.Num=app.AllData.SpikeWaveFormSample.Num;
                %   app.WaveformData.Type=app.AllData.SpikeWaveFormSample.Type;


               else
                   app.GoButton_2.Enable=false;
                   app.ClusterButton.Enable=false;
                   app.NewWindowButton.Enable=false;
                   
                   
               end
             end %End of the if batch flag
               
               
           end
         catch exception
             
              app.TaskNameDropDown.Enable=false;
              app.TaskNameDropDown.Items={'Loading Failed! Please check the data'};
              app.BehaviorSummary.Value={'Loading Error!'};
              throw(exception);
              
                
         end


        end

        % Callback function
        function OutputpathEditFieldValueChanged(app, event)
                app.ConfigButton.BackgroundColor=[1,0,0]; 
                value=app.OutputpathEditField.Value;
                 if ~strcmp(value(end),'\')  %Add a '\' if there is none
                    app.OutputpathEditField.Value=[value '\'];
                 end

        end

        % Callback function
        function SubjectNameEditFieldValueChanged(app, event)

               app.ConfigButton.BackgroundColor=[1,0,0]; 
               if app.LabButton.Value==1
                   app.BasePathEditField.Value = 'C:/Blip/DataFromSystem/';
                   app.TaskNameLabel.FontSize=13;
                   app.TaskNameLabel.FontSize=13;
                   app.AnalysesDropDownLabel.FontSize=13;
                   app.SpikeChannelDropDownLabel.FontSize=13;
                   app.StartTrialEditFieldLabel.FontSize=13;
                   app.EndTrialEditFieldLabel.FontSize=13;
               
               end
        end

        % Value changed function: BasePathEditField
        function BasePathEditFieldValueChanged(app, event)
            value = app.BasePathEditField.Value;
            %Add a '\' if there is none
            if ~strcmp(value(end),app.SystemLabel)
                app.BasePathEditField.Value=[value app.SystemLabel];
            end
        end

        % Value changed function: BehaviorFileDropDown
        function BehaviorFileDropDownValueChanged(app, event)
                 app.BehaviorFileDropDown.FontColor='k';   
                  %When there is a new item, first compare whether it's a new file
                  FileName=app.BehaviorFileDropDown.Value;
                  if ~strcmp(FileName(end-3:end),'.rst') && ~strcmp(FileName,app.BehaviorFileDropDown.Items{1}) && ~app.BatchCheckBox.Value
                      FileName=[FileName '.rst']; %First add a extension if there is none 
                      app.BehaviorFileDropDown.Value=FileName;
                  end
                  if ~strcmp(FileName(end-3:end),'.txt') && ~strcmp(FileName,app.BehaviorFileDropDown.Items{1}) && app.BatchCheckBox.Value
                       FileName=[FileName '.txt']; %First add a extension if there is none 
                      app.BehaviorFileDropDown.Value=FileName;
                  end
                       SelectIndex=strcmp(app.BehaviorFileDropDown.Items, FileName);
                   
                        if ~sum(SelectIndex)
                            %If the file is not yet exist in the list, add the item            
                            temp=app.BehaviorFileDropDown.Items; %First save the previous one
                            app.BehaviorFileDropDown.Items={temp{1},FileName,temp{2:end}};%Always list the most recent one in the first place
                           
                        elseif ~SelectIndex(1) %Reorder the items to make the most recent load file upward
                            temp=app.BehaviorFileDropDown.Items; %First save the previous one                  
                            temp_delete = erase( temp,FileName);
                            temp_delete=temp_delete(~cellfun(@isempty, temp_delete));
                            app.BehaviorFileDropDown.Items={temp{1}, temp{SelectIndex},temp_delete{2:end}};%Always list the most recent one in the first place
                        end 
                        if ~app.BatchCheckBox.Value
                            
                        
                        %Change the date box according to the file
                        FileName_temp=FileName(1:end-4);
                    
                       %Get File date Information
                        num = regexp(FileName_temp, '\d+', 'match');
                        RecordDateOriginal=cell2mat(num(1));
                        app.DateEditField.Value=RecordDateOriginal;
                        app.RawDataPath=strcat(app.BasePathEditField.Value,app.DateEditField.Value, app.SystemLabel);
                        
                        %Automatically fillin the waveform file
                        if app.WaveformButton.Value==1
                         %Automatically search for the waveform file
                            WaveformFileAuto=[app.DateEditField.Value 'SPK' FileName(end-4) '.wv'];
                            if isfile([app.RawDataPath,WaveformFileAuto])
                            app.NeuronFileDropDown.Value=WaveformFileAuto;
                            app.WaveformButton.Value=1;
                            NeuronFileDropDownValueChanged(app, event); 
                            else
                             app.NeuronFileDropDown.Value={app.NeuronFileDropDown.Items{1}};
                             app.WaveformButton.Value=0;
                             NeuronFileDropDownValueChanged(app, event);                       
                             end 
                        end
                        end %End of if the batch file not checked 


        end

        % Value changed function: NeuronFileDropDown
        function NeuronFileDropDownValueChanged(app, event)

            
            if ~app.BatchCheckBox.Value %Not Batch
                app.NeuronFileDropDown.FontColor='k'; 
                 app.NeuronFileDropDown.Enable=1;
            %When there is a new item, first compare whether it's a new file
                  FileName=app.NeuronFileDropDown.Value;
                  if ~strcmp(FileName(end-2:end),'.wv') && ~strcmp(FileName,app.NeuronFileDropDown.Items{1})
                      FileName=[FileName '.wv']; %First add a extension if there is none 
                      app.NeuronFileDropDown.Value=FileName;
                  end                      
                       SelectIndex=strcmp(app.NeuronFileDropDown.Items, FileName);
                   
                        if ~sum(SelectIndex)
                            %If the file is not yet exist in the list, add the item            
                            temp=app.NeuronFileDropDown.Items; %First save the previous one
                            app.NeuronFileDropDown.Items={temp{1},FileName,temp{2:end}};%Always list the most recent one in the first place
                           
                        elseif ~SelectIndex(1) %Reorder the items to make the most recent load file upward
                            temp=app.NeuronFileDropDown.Items; %First save the previous one                  
                            temp_delete = erase( temp,FileName);
                            temp_delete=temp_delete(~cellfun(@isempty, temp_delete));
                            app.NeuronFileDropDown.Items={temp{1}, temp{SelectIndex},temp_delete{2:end}};%Always list the most recent one in the first place
                        end  
            else %Batch
                if app.WaveformButton.Value 
                  app.NeuronFileDropDown.Value='Auto';
                   app.NeuronFileDropDown.Enable=0;
                end
            end
        end

        % Value changed function: AnalysesDropDown
        function AnalysesDropDownValueChanged(app, event)
      
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
           EditIndex=0;
           %Update the summary board
           EndTrialEditFieldValueChanged(app, event);
           StartTrialEditFieldValueChanged(app, event)
           
            if app.BatchCheckBox.Value==1
                %Get file path              
                AllFiles=app.BatchData.PackedFilePath;
                RawFilesPath=app.BatchData.RawFilePathWithoutFile;
                %Get spike channel
                SpikeChannelAll=app.BatchData.ChannelNumber;
                %Get analysis protocol type and protocol
                ProtocolType=app.TaskNameDropDown.Value;
                Protocol=app.AnalysesDropDown.Value;
                TrialInterval=app.BatchData.TrialInterval;
                
                %Update the summary board
                 BatchFileSummaryUpate=sprintf('File Number: %d\n',(app.EndTrialEditField.Value-app.StartTrialEditField.Value)+1);               
                 
                 app.BehaviorSummary.Value{2}=BatchFileSummaryUpate;
                 app.BehaviorSummary.Value=app.BehaviorSummary.Value(1:2);


                  if ProtocolType==1
                      TotalFileNum=app.EndTrialEditField.Value-app.StartTrialEditField.Value+1;
                      disp("Start processing!!!... ...");
                      Progressbar=waitbar(0,'Start processing....'); 
                     for ii=app.StartTrialEditField.Value:app.EndTrialEditField.Value   
                        %Clear previous files
                        app.UseData=[];
                        app.GoodData=[];
                        app.AllData=[];
                        %Load current file                    
                        load(AllFiles{ii});
                        if app.GoodDataButton_2.Value==1 %If using good data
                           app.UseData=GoodData; 
                           app.UseData.DataType={'Good Data'};
                           app.UseData.BatchFileName=app.BehaviorFileDropDown.Value;
                      
                        else
                           app.UseData=AllData; %If using all data
                           app.UseData.DataType={'All Data'};
                           app.UseData.BatchFileName=app.BehaviorFileDropDown.Value;
                        end
                        
                        app.SpikeData.SpikeTimeData= app.UseData.SpikeTimeData;
                        app.SpikeData.SpikeBinData= app.UseData.SpikeBinData;
                        ChannelID=app.UseData.ChannelID;
                        ClusterID=app.UseData.ClusterID;
                           
                        %Load SpikeChanne;
                        SpikeChannel=SpikeChannelAll(ii,1):SpikeChannelAll(ii,2);
                        app.UseData.SpikeChannel=SpikeChannel;
                        
                     
                        if SpikeChannel(1)>0          
                         
                         app.UseData.SpikeTimeData=app.SpikeData.SpikeTimeData(SpikeChannel,:,:);
                         
                         app.UseData.SpikeBinData=app.SpikeData.SpikeBinData(SpikeChannel,:,:);
                         app.UseData.ChannelID=ChannelID(SpikeChannel);
                         app.UseData.ClusterID=ClusterID(SpikeChannel);
                        %{
                        else
                         %For future multichanel recordings                                              
                         app.UseData.SpikeTimeData=app.SpikeData.SpikeTimeData(:,:,:);
                         app.UseData.SpikeBinData=app.SpikeData.SpikeBinData(:,:,:); 
                %}
                        end
                        %Add data path
                        app.UseData.Path= app.PackedDataPath;
                        app.UseData.PathRaw= RawFilesPath{ii};
                        
                      Progress=sprintf('Processing File : %d, %d Files remaining\n',ii,TotalFileNum-ii);               
                      app.BehaviorSummary.Value=[ app.BehaviorSummary.Value; Progress];
                       waitbar(ii/(-app.StartTrialEditField.Value+app.EndTrialEditField.Value+1), Progressbar,Progress);      
                     
                       %Batch analysis 
                       StartTrial=TrialInterval(ii,1);
                       EndTrial=TrialInterval(ii,2);
                       if StartTrial<=0
                           StartTrial=1;
                       end
                       if EndTrial<=0
                           EndTrial=app.UseData.TrialNum;
                       end
                       
                       
                        DataAnalysisProtocolSwitchyard(app.UseData, StartTrial,EndTrial,Protocol,EditIndex,app.ShowfigCheckBox.Value,app.OutputCheckBox.Value);                  


                     end %End of the for loop
                     
                      FinishedLabel=sprintf('Batch Processing Completed\n');               
                      app.BehaviorSummary.Value=[ app.BehaviorSummary.Value; FinishedLabel];
                 %Window shaking
                
                for mm=1:50 %Shake 50 times
                    current_position=app.UIFigure.Position;
                    current_position(1)=current_position(1)+20*(-1)^mm;
                    app.UIFigure.Position=current_position;
                end
                 waitbar(1, Progressbar,'Processing completed');                      
                 disp("Processing completed!!!");    
                 
                  else     
                     %After-Batch analysis
                     disp("Start processing!!!... ...");
                     app.BatchData.BatchFileName=app.BehaviorFileDropDown.Value;
                    DataAnalysisProtocolSwitchyard(app.BatchData, app.StartTrialEditField.Value, app.EndTrialEditField.Value,Protocol,EditIndex,app.ShowfigCheckBox.Value,app.OutputCheckBox.Value);             
                  
                FinishedLabel=sprintf('Processing Completed\n');       
                app.BehaviorSummary.Value=[ app.BehaviorSummary.Value; FinishedLabel];
                 
                  disp("Processing completed!!!");    
                    
                    %Window shaking
                
                for mm=1:50 %Shake 50 times
                    current_position=app.UIFigure.Position;
                    current_position(1)=current_position(1)+20*(-1)^mm;
                    app.UIFigure.Position=current_position;
                end
                
                     
                 end %End of the if protocol type ==1
            else
                %Single file analysis
                %{
                if app.UpdateIndex
                    SpikeChannel=app.SpikeChannelDropDown.Value+1;
                    app.SpikeData.SpikeTimeData= app.UseData.SpikeTimeData;
                    app.SpikeData.SpikeBinData= app.UseData.SpikeBinData;
                else
                    SpikeChannel=app.SpikeChannelDropDown.Value;
                    
                end
                %}
              %  UpdateDataButtonPushed(app,event);
                SpikeChannel=app.SpikeChannelDropDown.Value;


                 ChannelID=app.SpikeData.ChannelID;
                 ClusterID=app.SpikeData.ClusterID;
                 if SpikeChannel>1 && SpikeChannel<size(app.SpikeData.SpikeTimeData,1)+2
                     app.UseData.SpikeChannel=SpikeChannel-1;
                     app.UseData.ChannelID=ChannelID(SpikeChannel-1);
                     app.UseData.ClusterID=ClusterID(SpikeChannel-1);
                     
                     app.UseData.SpikeTimeData=app.SpikeData.SpikeTimeData(SpikeChannel-1,:,:);
                     app.UseData.SpikeBinData=app.SpikeData.SpikeBinData(SpikeChannel-1,:,:);
                 elseif SpikeChannel==size(app.SpikeData.SpikeTimeData,1)+2
                     %All online channel                  
                     AllChannelNum=app.SpikeData.TotalSpikeNum;
                     ValidOnlineChannel=find(AllChannelNum(1:min(numel(AllChannelNum),3))>0);
                     app.UseData.ChannelID=ChannelID(ValidOnlineChannel);
                     app.UseData.ClusterID=ClusterID(ValidOnlineChannel);
                     
                     app.UseData.SpikeChannel=ValidOnlineChannel;
                     app.UseData.SpikeTimeData=app.SpikeData.SpikeTimeData(ValidOnlineChannel,:,:);
                     app.UseData.SpikeBinData=app.SpikeData.SpikeBinData(ValidOnlineChannel,:,:); 
                 elseif SpikeChannel==size(app.SpikeData.SpikeTimeData,1)+3
                     %All plexon channel
                     AllChannelNum=app.SpikeData.TotalSpikeNum;
                     ValidPlexonChannel=4:size( AllChannelNum,2);                     
                     app.UseData.SpikeChannel=ValidPlexonChannel;
                     app.UseData.ChannelID=ChannelID(ValidPlexonChannel);
                     app.UseData.ClusterID=ClusterID(ValidPlexonChannel);                    
                     app.UseData.SpikeTimeData=app.SpikeData.SpikeTimeData(ValidPlexonChannel,:,:);
                     app.UseData.SpikeBinData=app.SpikeData.SpikeBinData(ValidPlexonChannel,:,:); 
                 elseif SpikeChannel==size(app.SpikeData.SpikeTimeData,1)+4
                     %All LFP channel
                     cd(app.UseData.LFPPath);
                     load(app.UseData.LFPName)
                     LFP=LFPData.LFP;
                     LFPTime=LFPData.LFPTime;
                     LFPSR=LFPData.LFPSamplingRate;
                     
                     if app.GoodDataButton_2.Value==1 %If using good data
                         GoodData=LFPData.GoodData;
                         
                         app.UseData.LFPChannel=LFP(GoodData);
                          app.UseData.LFPTime=LFPTime;
                          app.UseData.LFPSR=LFPSR;
                          
                          
                     else
                         BonusData=LFPData.BonusData;
                         
                         app.UseData.LFPChannel=LFP(~BonusData);
                         app.UseData.LFPTime=LFPTime;
                         app.UseData.LFPSR=LFPSR;
                         
                         
                     end


                 end
                 
                Protocol=app.AnalysesDropDown.Value;
                %Add data path
                app.UseData.Path= app.PackedDataPath;
                app.UseData.PathRaw= app.RawDataPath;


                disp("Start processing!!!... ...")
 
                DataAnalysisProtocolSwitchyard(app.UseData, app.StartTrialEditField.Value, app.EndTrialEditField.Value,Protocol,EditIndex,app.ShowfigCheckBox.Value,app.OutputCheckBox.Value);             
                
                FinishedLabel=sprintf('Processing Completed\n');       
                app.BehaviorSummary.Value=[ app.BehaviorSummary.Value; FinishedLabel];
                 disp("Processing completed!!!");    
                %Window shaking
                
                for mm=1:50 %Shake 50 times
                    current_position=app.UIFigure.Position;
                    current_position(1)=current_position(1)+20*(-1)^mm;
                    app.UIFigure.Position=current_position;
                end


            end %End of the if batch analysis ==1


        end

        % Button pushed function: ECodeButton
        function ECodeButtonPushed(app, event)
             edit Ecode.m;
        end

        % Button pushed function: UpdateDataButton
        function UpdateDataButtonPushed(app, event)
            if app.GoodDataButton_2.Value
                %Good data;
                app.UseData=app.GoodData;
          
                app.UseData.DataType={'Good Data'};
                app.SpikeData.SpikeTimeData=app.GoodData.SpikeTimeData;
                app.SpikeData.SpikeBinData=app.GoodData.SpikeBinData;
                app.SpikeData.ChannelID=app.GoodData.ChannelID;
                app.SpikeData.ClusterID=app.GoodData.ClusterID;


                 if isfield(app.GoodData,'LFPValidIndex')
                    app.SpikeData.LFPValidIndex=app.GoodData.LFPValidIndex;
                 else
                    app.SpikeData.LFPValidIndex=0;
                 end
                
                
            else
                %All data;
                app.UseData=app.AllData;
                app.UseData.DataType={'All Data'};
                app.SpikeData.SpikeTimeData=app.AllData.SpikeTimeData;
                 app.SpikeData.SpikeBinData=app.AllData.SpikeBinData;
                 app.SpikeData.ChannelID=app.AllData.ChannelID;
                 app.SpikeData.ClusterID=app.AllData.ClusterID;
                 
                            
                 if isfield(app.AllData,'LFPValidIndex')
                      app.SpikeData.LFPValidIndex=app.AllData.LFPValidIndex;
                 else
                      app.SpikeData.LFPValidIndex=0;
                 end
                
                
            end
           
           
                   app.StartTrialEditField.Value=1;    
                   app.EndTrialEditField.Value=app.UseData.TrialNum;  
                   
           if sum(isnan(app.SpikeData.SpikeTimeData),'All')~=numel(app.SpikeData.SpikeTimeData)
                   
                   SpikeChannelNumber=size(app.SpikeData.SpikeTimeData,1); 
                  
                       if SpikeChannelNumber>1
                            app.SpikeChannelDropDown.Enable=true;
                       else
                           app.SpikeChannelDropDown.Enable=false; 
                       end
                       app.SpikeChannelDropDown.FontColor='k';
                       app.SpikeChannelDropDown.FontWeight='Bold';
                       
                       for i=1:SpikeChannelNumber
                           TotalSpikeNumber=sum(~isnan(app.SpikeData.SpikeTimeData(i,:,:)),'all')-size(squeeze(app.SpikeData.SpikeTimeData(i,:,:)),2)*2;
                           app.SpikeChannelDropDown.Items{i}=strcat(int2str(i),'Channel: ',int2str(app.SpikeData.ChannelID(i)),' ID: ', int2str(app.SpikeData.ClusterID(i)),'(',num2str(TotalSpikeNumber),')');
                           app.SpikeChannelDropDown.ItemsData(i)=i+1;
                           app.SpikeData.TotalSpikeNum(i)=TotalSpikeNumber;
                       
                       end
                       
                       app.SpikeChannelDropDown.Items{i+1}='All online channels';
                       app.SpikeChannelDropDown.ItemsData(i+1)=i+2;
                       if SpikeChannelNumber>3
                           app.SpikeChannelDropDown.Items{i+2}='All plexon channels';
                           app.SpikeChannelDropDown.ItemsData(i+2)=i+3;
                       end
                       if app.SpikeData.LFPValidIndex
                           app.SpikeChannelDropDown.Items{i+3}='All LFP channels';
                           app.SpikeChannelDropDown.ItemsData(i+3)=i+4;
                           
                       end
           else
               %No spike channel
                       app.SpikeChannelDropDown.Enable=false; 
           end
                   %{
               %    if sum(isnan(app.UseData.SpikeTimeData),'All')~=numel(app.UseData.SpikeTimeData)
                  if sum(isnan(app.SpikeData.SpikeTimeData),'All')~=numel(app.SpikeData.SpikeTimeData)
                   
                       SpikeChannelNumber=size(app.UseData.SpikeTimeData,1);                   
                       app.SpikeChannelDropDown.Enable=true;
                       app.SpikeChannelDropDown.FontColor='k';
                       app.SpikeChannelDropDown.FontWeight='Bold';
                       for i=1:SpikeChannelNumber
                            TotalSpikeNumber=sum(~isnan(app.UseData.SpikeTimeData(i,:,:)),'all');
                          % app.SpikeChannelDropDown.Items{i}=strcat('Spike Channel : ', int2str(i),'(',num2str(TotalSpikeNumber),')');
                          app.SpikeChannelDropDown.Items{i}=strcat(int2str(i),'Channel: ',int2str(app.SpikeData.ChannelID(i)),' ID: ', int2str(app.SpikeData.ClusterID(i)),'(',num2str(TotalSpikeNumber),')');
                           app.SpikeChannelDropDown.ItemsData(i)=i;
                       
                       end
                   
                   end
                   app.UpdateIndex=1;
                   %}
  
        end

        % Button pushed function: EditProtocolButton
        function EditProtocolButtonPushed(app, event)
            EditIndex=1;
            DataAnalysisProtocolSwitchyard(app.UseData, app.StartTrialEditField.Value, app.EndTrialEditField.Value,app.AnalysesDropDown.Value,EditIndex);
        end

        % Value changed function: BehaviorFileButton
        function BehaviorFileButtonValueChanged(app, event)
            value = app.BehaviorFileButton.Value;
            
        end

        % Button pushed function: GoButton_2
        function GoButton_2Pushed(app, event)
       
         if ~(app.BatchCheckBox.Value==1 && app.WaveformButton.Value==1 )
              %Single neurons
             if ~isempty(app.WaveformData)
                %Plot the waveform for each unit
               % UpdateDataButtonPushed(app,event);
              % if app.UpdateIndex
                   SelectedChannel=app.SpikeChannelDropDown.Value-1;
              % else
               %    SelectedChannel=app.SpikeChannelDropDown.Value-1;
                   
              % end


                if SelectedChannel>3 & SelectedChannel<3+size(app.WaveformData.Wform,1)+1
                    plot(app.UIAxes,app.WaveformData.Wform(SelectedChannel-3,:),'LineWidth',3,'Color','r'); 
                elseif SelectedChannel<=3 
                    plot(app.UIAxes,app.WaveformData.Wform(min(SelectedChannel,3),:),'LineWidth',3,'Color','b');
                elseif SelectedChannel==3+size(app.WaveformData.Wform,1)+1
                     plot(app.UIAxes,app.WaveformData.Wform(1:size(app.WaveformData.Wform),:)','LineWidth',3);
                elseif SelectedChannel==3+size(app.WaveformData.Wform,1)+2
                     plot(app.UIAxes,app.WaveformData.Wform','LineWidth',3);
                    
                end
                
                  
                   % hold(app.UIAxes,'on');
                    %{
                SpikeType=length(unique(app.WaveformData.Type))-1;
                 for i=1:SpikeType
                   % T0=[0: unique( app.WaveformData.SR( app.WaveformData.Type==i-1)):(unique( app.WaveformData.DataPointNum( app.WaveformData.Type==i-1))-1)*unique( app.WaveformData.SR( app.WaveformData.Type==i-1))];
                    T0=0:1/unique( app.WaveformData.SR(app.WaveformData.Type==i-1)):1/unique( app.WaveformData.SR( app.WaveformData.Type==i-1))*size(app.WaveformData.Wform(app.WaveformData.Type==i-1,:),2);
                     T0=T0(2:end)*10^3;
                    plot(app.UIAxes,T0,app.WaveformData.Wform(app.WaveformData.Type==i-1,:)','color',[0.3+(i-1)/2,0.8-(i-1)/2,0.1])
                    hold(app.UIAxes,'on')
                 end
                    %}
              else
                    msgbox('Lighten the waveform button first');
             end
             
        else
            %Auto batch
             %Update the summary board
           EndTrialEditFieldValueChanged(app, event);
           StartTrialEditFieldValueChanged(app, event);
        
             %Update the summary board
                 BatchFileSummaryUpdate=sprintf('File Number: %d\n',(app.EndTrialEditField.Value-app.StartTrialEditField.Value)+1);               


               WaveformFileName=app.BatchData.WaveFormFile(app.StartTrialEditField.Value: app.EndTrialEditField.Value);
               WaveformFile=app.BatchData.WaveFormPath(app.StartTrialEditField.Value: app.EndTrialEditField.Value);
               WaveformFileFlag=app.BatchData.WaveFormFileFlag(app.StartTrialEditField.Value: app.EndTrialEditField.Value);
               WaveformRawFileName=app.BatchData.FileName(app.StartTrialEditField.Value: app.EndTrialEditField.Value);
               
               
            %   WaveformFileValid=WaveformFile(WaveformFileFlag==1);
               WaveformFileNameValid=WaveformFileName(WaveformFileFlag==1);
               WaveformRawFileNameValid=WaveformRawFileName(WaveformFileFlag==1);
               WaveFormPathValid=WaveformFile(WaveformFileFlag==1);
               
               %Get spike channel
               SpikeChannelAll=app.BatchData.ChannelNumber(app.StartTrialEditField.Value: app.EndTrialEditField.Value,:);
               SpikeChanneValid=SpikeChannelAll(WaveformFileFlag==1,:);
               
               TotalFileNum=size(SpikeChanneValid,1);
               disp("Start processing!!!... ...");
                Progressbar=waitbar(0,'Start processing waveform....'); 
                      
               
               for i=1: TotalFileNum
                  Progress=sprintf('Processing File : %d, %d Files remaining\n',i,TotalFileNum-i);               
                   app.BehaviorSummary.Value=[ app.BehaviorSummary.Value; Progress];
                   waitbar(i/(-app.StartTrialEditField.Value+app.EndTrialEditField.Value+1), Progressbar,Progress);      
                  %  app.WaveformData=
                  % app.WaveformData=LoadWaveform( WaveformFileValid{i});
                  app.WaveformData.Path=WaveFormPathValid{i};
                 % app.WaveformData.Order=BatchFileOut.Order;
                   app.WaveformData.RawFileName=WaveformRawFileNameValid{i};
                   SpikeChannel_Start =SpikeChanneValid(i,1);
                   SpikeChannel_End =SpikeChanneValid(i,2);
                    for ii =  SpikeChannel_Start:SpikeChannel_End
                        try
                        PlotWaveForm(app.WaveformData,WaveformFileNameValid{i},ii,app.ShowfigCheckBox.Value,app.OutputCheckBox.Value);
                        catch
                            keyboard
                        end
                    end
               end
               
                 FinishedLabel=sprintf('Batch Processing Completed\n');               
                   app.BehaviorSummary.Value=[ app.BehaviorSummary.Value; FinishedLabel];
                 %Window shaking
                
                for mm=1:50 %Shake 50 times
                    current_position=app.UIFigure.Position;
                    current_position(1)=current_position(1)+20*(-1)^mm;
                    app.UIFigure.Position=current_position;
                end
                 waitbar(1, Progressbar,'Processing completed');                      
                 disp("Waveform Processing completed!!!");    


              end
        end

        % Button pushed function: AddProtocolButton
        function AddProtocolButtonPushed(app, event)
           
            edit DataAnalysisProtocolSwitchyard;
            edit DataAnalysisListSwitchyard;
        end

        % Button pushed function: EditLoadPackButton
        function EditLoadPackButtonPushed(app, event)
    
            edit PackData;
            edit LoadAndExportData;
            edit LoadWaveform.m
            if app.BatchCheckBox.Value==1
                edit CheckBatchFile.m
            end
        end

        % Button pushed function: NewWindowButton
        function NewWindowButtonPushed(app, event)
            app.WaveformData.RawFileName=app.BehaviorFileDropDown.Value;
            PlotWaveForm(app.WaveformData,app.NeuronFileDropDown.Value,app.SpikeChannelDropDown.Value-1,1,app.OutputCheckBox.Value)
        end

        % Button pushed function: EditButton_2
        function EditButton_2Pushed(app, event)
             edit PlotWaveForm.m;
             edit PCAClusteringPlot.m
        end

        % Button pushed function: ClusterButton
        function ClusterButtonPushed(app, event)
            PCAClusteringPlot(app.WaveformData,app.NeuronFileDropDown.Value,app.SpikeChannelDropDown.Value-1,1,app.OutputCheckBox.Value);
        end

        % Value changed function: LabCheckBox
        function LabCheckBoxValueChanged(app, event)
            value = app.LabCheckBox.Value;
            if value==1
                app.BasePathEditField.Value='C:\Blip\_Data_Recorded\';
                app.TaskNameDropDown.FontSize=13;
                app.AnalysesDropDownLabel.FontSize=13;
                app.SpikeChannelLabel.FontSize=13;
                app.StartTrialEditFieldLabel.FontSize=13;
                app.EndTrialEditFieldLabel.FontSize=13;
                BasePathEditFieldValueChanged(app, event);
            else
                app.BasePathEditField.Value=app.RecoverPathFromBatch;%Don't need batch on the lab computer
                
            end
            
        end

        % Value changed function: BatchCheckBox
        function BatchCheckBoxValueChanged(app, event)
            value = app.BatchCheckBox.Value;
            if value==1 %If batch was checked
                %Use the batch file base path
                if app.PCButton.Value==1 %PC
                    MarkerFolder='DataHub';
                else
                    %Mac
                    MarkerFolder='DataAnalysis';
                    
                end
                
                
                Flag=strfind(app.BasePathEditField.Value,MarkerFolder);
                BatchDirectory=app.BasePathEditField.Value(1:Flag+length(MarkerFolder));
                ResultPath=strcat(BatchDirectory,'Results',BatchDirectory(end));%Result file
                BatchFilePath=strcat(ResultPath,'BatchFile',BatchDirectory(end));%Batch file folder
             if ~exist(BatchFilePath)
                 mkdir(BatchFilePath)
             end
             
                app.BasePathEditField.Value=BatchFilePath;
                app.DateEditField.Value='BATCH';%Marker the batch analysis
                app.ShowfigCheckBox.Value=0;%By default turn off the figure
                NeuronFileDropDownValueChanged(app, event);


            else
                
                app.BasePathEditField.Value=app.RecoverPathFromBatch;
                %Get the current date
                 formatOut = 'mmddyy';
                 app.DateEditField.Value=datestr(now,formatOut);
                 %Disable the task name drop down
                app.TaskNameDropDown.Enable=false;
                app.TaskNameDropDown.Editable=false;
                app.ShowfigCheckBox.Value=1;%By default turn on the figure
                NeuronFileDropDownValueChanged(app, event);
                
            
            end


        end

        % Button pushed function: CLFButton
        function CLFButtonPushed(app, event)
            close all;
            clc;
        end

        % Value changed function: TaskNameDropDown
        function TaskNameDropDownValueChanged(app, event)
            value = app.TaskNameDropDown.Value;
            if value==2 % Batch analysis
                %Show the analysis protocol
                app.AnalysesDropDown.Items= DataAnalysisListSwitchyard(app.BATCH);%Show all the batch protocols;
                app.ShowfigCheckBox.Value=1;%By default
            else
                app.AnalysesDropDown.Items=app.RecoverAnalysisFromBatch;%Restore the protocol analysis list;
                app.ShowfigCheckBox.Value=0;%By default
            end
        end

        % Value changed function: SpikeChannelDropDown
        function SpikeChannelDropDownValueChanged(app, event)
            value = app.SpikeChannelDropDown.Value;
            if app.GoButton_2.Enable
               GoButton_2Pushed(app, event);
            end
            
        end

        % Value changed function: EndTrialEditField
        function EndTrialEditFieldValueChanged(app, event)
            value = app.EndTrialEditField.Value;
            if   app.BatchCheckBox.Value==1
                %Update the summary board
                 BatchFileSummaryUpate=sprintf('File Number: %d\n',(value-app.StartTrialEditField.Value)+1);               
                 
                 app.BehaviorSummary.Value{2}=BatchFileSummaryUpate;
            end
            
        end

        % Value changed function: StartTrialEditField
        function StartTrialEditFieldValueChanged(app, event)
            value = app.StartTrialEditField.Value;
            if   app.BatchCheckBox.Value==1
                %Update the summary board
                 BatchFileSummaryUpate=sprintf('File Number: %d\n',(app.EndTrialEditField.Value-value)+1);               
                 
                 app.BehaviorSummary.Value{2}=BatchFileSummaryUpate;
            end
        end

        % Callback function
        function ShowFolderButtonPushed(app, event)
                uigetdir(app.BasePathEditField.Value);
        end

        % Value changed function: WaveformButton
        function WaveformButtonValueChanged(app, event)
            value = app.WaveformButton.Value;
            if value==1
                NeuronFileDropDownValueChanged(app, event)
                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'UI Figure';
            app.DataLoadPanel = uipanel(app.UIFigure);
            app.DataLoadPanel.Title = 'Data Load ';
            app.BasePathLabel = uilabel(app.DataLoadPanel);
            app.BasePathLabel.Text = 'Base Path:';
            app.BasePathEditField = uieditfield(app.DataLoadPanel, 'text');
            app.BasePathEditField.ValueChangedFcn = createCallbackFcn(app, @BasePathEditFieldValueChanged, true);
            app.BasePathEditField.Value = 'H:\ProgramesInOHLab\DataAnalysis\DataFromSystem\';
            app.OSButtonGroup = uibuttongroup(app.DataLoadPanel);
            app.OSButtonGroup.Title = 'OS';
            app.PCButton = uiradiobutton(app.OSButtonGroup);
            app.PCButton.Text = 'PC';
            app.PCButton.Value = true;
            app.MacButton = uiradiobutton(app.OSButtonGroup);
            app.MacButton.Text = 'Mac';
            app.LabCheckBox = uicheckbox(app.OSButtonGroup);
            app.LabCheckBox.ValueChangedFcn = createCallbackFcn(app, @LabCheckBoxValueChanged, true);
            app.LabCheckBox.Text = 'Lab?';
            app.LoadButton = uibutton(app.DataLoadPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load ';
            app.BrowseButton = uibutton(app.DataLoadPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.BatchCheckBox = uicheckbox(app.DataLoadPanel);
            app.BatchCheckBox.ValueChangedFcn = createCallbackFcn(app, @BatchCheckBoxValueChanged, true);
            app.BatchCheckBox.Text = 'Batch?';
            app.ECodeButton = uibutton(app.DataLoadPanel, 'push');
            app.ECodeButton.ButtonPushedFcn = createCallbackFcn(app, @ECodeButtonPushed, true);
            app.ECodeButton.Text = 'ECode';
            app.DateEditFieldLabel = uilabel(app.DataLoadPanel);
            app.DateEditFieldLabel.Text = 'Date:';
            app.DateEditField = uieditfield(app.DataLoadPanel, 'text');
            app.DateEditField.Value = '072919';
            app.EditLoadPackButton = uibutton(app.DataLoadPanel, 'push');
            app.EditLoadPackButton.ButtonPushedFcn = createCallbackFcn(app, @EditLoadPackButtonPushed, true);
            app.EditLoadPackButton.Text = 'Edit Load &Pack';
            app.RePackCheckBox = uicheckbox(app.DataLoadPanel);
            app.RePackCheckBox.Text = 'RePack?';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.BehaviorFileButton = uibutton(app.ButtonGroup, 'state');
            app.BehaviorFileButton.ValueChangedFcn = createCallbackFcn(app, @BehaviorFileButtonValueChanged, true);
            app.BehaviorFileButton.Text = 'Behavior File';
            app.WaveformButton = uibutton(app.ButtonGroup, 'state');
            app.WaveformButton.ValueChangedFcn = createCallbackFcn(app, @WaveformButtonValueChanged, true);
            app.WaveformButton.Text = 'Waveform';
            app.BehaviorFileDropDown = uidropdown(app.ButtonGroup);
            app.BehaviorFileDropDown.Items = {'Select/Input a file'};
            app.BehaviorFileDropDown.Editable = 'on';
            app.BehaviorFileDropDown.ValueChangedFcn = createCallbackFcn(app, @BehaviorFileDropDownValueChanged, true);
            app.BehaviorFileDropDown.Value = 'Select/Input a file';
            app.NeuronFileDropDown = uidropdown(app.ButtonGroup);
            app.NeuronFileDropDown.Items = {'Select/Input a file'};
            app.NeuronFileDropDown.Editable = 'on';
            app.NeuronFileDropDown.ValueChangedFcn = createCallbackFcn(app, @NeuronFileDropDownValueChanged, true);
            app.NeuronFileDropDown.Value = 'Select/Input a file';
            app.TaskAnalysisPanel = uipanel(app.UIFigure);
            app.TaskAnalysisPanel.Title = 'Task Analysis';
            app.AnalysesDropDownLabel = uilabel(app.TaskAnalysisPanel);
            app.AnalysesDropDownLabel.Text = 'Analyses';
            app.AnalysesDropDown = uidropdown(app.TaskAnalysisPanel);
            app.AnalysesDropDown.ValueChangedFcn = createCallbackFcn(app, @AnalysesDropDownValueChanged, true);
            app.TaskNameLabel = uilabel(app.TaskAnalysisPanel);
            app.TaskNameLabel.Text = 'Task Name';
            app.ButtonGroup_2 = uibuttongroup(app.TaskAnalysisPanel);
            app.GoodDataButton_2 = uiradiobutton(app.ButtonGroup_2);
            app.GoodDataButton_2.Text = 'Good Data';
            app.GoodDataButton_2.Value = true;
            app.AllDataButton = uiradiobutton(app.ButtonGroup_2);
            app.AllDataButton.Text = 'All Data';
            app.UpdateDataButton = uibutton(app.ButtonGroup_2, 'push');
            app.UpdateDataButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateDataButtonPushed, true);
            app.UpdateDataButton.Text = 'Update Data';
            app.StartTrialEditFieldLabel = uilabel(app.TaskAnalysisPanel);
            app.StartTrialEditFieldLabel.Text = 'Start Trial';
            app.StartTrialEditField = uieditfield(app.TaskAnalysisPanel, 'numeric');
            app.StartTrialEditField.ValueChangedFcn = createCallbackFcn(app, @StartTrialEditFieldValueChanged, true);
            app.EndTrialEditFieldLabel = uilabel(app.TaskAnalysisPanel);
            app.EndTrialEditFieldLabel.Text = 'End Trial';
            app.EndTrialEditField = uieditfield(app.TaskAnalysisPanel, 'numeric');
            app.EndTrialEditField.ValueChangedFcn = createCallbackFcn(app, @EndTrialEditFieldValueChanged, true);
            app.Lamp = uilamp(app.TaskAnalysisPanel);
            app.BehaviorSummary = uitextarea(app.TaskAnalysisPanel);
            app.SpikeChannelLabel = uilabel(app.TaskAnalysisPanel);
            app.SpikeChannelLabel.Text = 'Spike Channel';
            app.SpikeChannelDropDown = uidropdown(app.TaskAnalysisPanel);
            app.SpikeChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @SpikeChannelDropDownValueChanged, true);
            app.ProtocolButtonPanel = uipanel(app.TaskAnalysisPanel);
            app.ProtocolButtonPanel.Title = 'Protocol Button';
            app.AddProtocolButton = uibutton(app.ProtocolButtonPanel, 'push');
            app.AddProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @AddProtocolButtonPushed, true);
            app.AddProtocolButton.Text = 'Add Protocol';
            app.EditProtocolButton = uibutton(app.TaskAnalysisPanel, 'push');
            app.EditProtocolButton.ButtonPushedFcn = createCallbackFcn(app, @EditProtocolButtonPushed, true);
            app.EditProtocolButton.Text = 'Edit Protocol';
            app.GoButton = uibutton(app.TaskAnalysisPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go!';
            app.ButtonGroup_3 = uibuttongroup(app.TaskAnalysisPanel);
            app.ShowfigCheckBox = uicheckbox(app.ButtonGroup_3);
            app.ShowfigCheckBox.Text = 'Show fig?';
            app.OutputCheckBox = uicheckbox(app.ButtonGroup_3);
            app.OutputCheckBox.Text = 'Output?';
            app.TaskNameDropDown = uidropdown(app.TaskAnalysisPanel);
            app.TaskNameDropDown.ValueChangedFcn = createCallbackFcn(app, @TaskNameDropDownValueChanged, true);
            app.CLFButton = uibutton(app.TaskAnalysisPanel, 'push');
            app.CLFButton.ButtonPushedFcn = createCallbackFcn(app, @CLFButtonPushed, true);
            app.CLFButton.Text = 'CLF!';
            app.WaveformPanel = uipanel(app.UIFigure);
            app.WaveformPanel.Title = 'Waveform';
            app.UIAxes = uiaxes(app.WaveformPanel);
            title(app.UIAxes, 'Spikes')
            app.Lamp_2 = uilamp(app.WaveformPanel);
            app.GoButton_2 = uibutton(app.WaveformPanel, 'push');
            app.GoButton_2.ButtonPushedFcn = createCallbackFcn(app, @GoButton_2Pushed, true);
            app.GoButton_2.Text = 'Go!';
            app.NewWindowButton = uibutton(app.WaveformPanel, 'push');
            app.NewWindowButton.ButtonPushedFcn = createCallbackFcn(app, @NewWindowButtonPushed, true);
            app.NewWindowButton.Text = 'New Window';
            app.EditButton_2 = uibutton(app.WaveformPanel, 'push');
            app.EditButton_2.ButtonPushedFcn = createCallbackFcn(app, @EditButton_2Pushed, true);
            app.EditButton_2.Text = 'Edit';
            app.ClusterButton = uibutton(app.WaveformPanel, 'push');
            app.ClusterButton.ButtonPushedFcn = createCallbackFcn(app, @ClusterButtonPushed, true);
            app.ClusterButton.Text = 'Cluster';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataHub

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end